classdef uibubblecloud_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uibubblecloudDemoAppUIFigure  matlab.ui.Figure
        uibubblecloud                 uibubblecloud
        plotButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            n = [58 115 81 252 180 124 40 80 50 20]';
            loc = ["NJ" "NY" "MA" "OH" "NH" "ME" "CT" "PA" "RI" "VT"]';
            plant = ["Plant A" "Plant A" "Plant A" "Plant A" ...
                "Plant A" "Plant A" "Plant A" "Plant B" "Plant B" "Plant B"]';
            tbl = table(n,loc,plant,'VariableNames',["Mislabeled" "State" "Manufacturing Plant"]);
            plot(app.uibubblecloud,tbl,"Mislabeled","State")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uibubblecloudDemoAppUIFigure = uifigure('Visible', 'off');
            app.uibubblecloudDemoAppUIFigure.Name = 'uibubblecloud Demo App';
            app.plotButton = uibutton(app.uibubblecloudDemoAppUIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.uibubblecloud = uibubblecloud(app.uibubblecloudDemoAppUIFigure);
            % Show the figure after all components are created
            app.uibubblecloudDemoAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uibubblecloud_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.uibubblecloudDemoAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uibubblecloudDemoAppUIFigure)
        end
    end
end