classdef uigeoaxes_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uigeoaxesDemoAppUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        ControlsGridLayout        matlab.ui.container.GridLayout
        ResetViewButton           matlab.ui.control.Button
        BasemapDropDown           matlab.ui.control.DropDown
        BasemapLabel              matlab.ui.control.Label
        uigeoaxes                 uigeoaxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            latitude = [47.6062 45.5152 37.7749 34.0522];
            longitude = [-122.3321 -122.6784 -122.4194 -118.2437];

            geoplot(app.uigeoaxes, latitude, longitude, '-o', ...
                'Color', [0.8500 0.3250 0.0980], ...
                'LineWidth', 2, ...
                'MarkerSize', 7, ...
                'MarkerFaceColor', [0.8500 0.3250 0.0980]);
            geobasemap(app.uigeoaxes, app.BasemapDropDown.Value);
            geolimits(app.uigeoaxes, [32 50], [-126 -116]);
            title(app.uigeoaxes, 'West Coast Route');
        end

        % Value changed function: BasemapDropDown
        function BasemapDropDownValueChanged(app, event)
            geobasemap(app.uigeoaxes, event.Value);
            geolimits(app.uigeoaxes, [32 50], [-126 -116]);
        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)
            geolimits(app.uigeoaxes, [32 50], [-126 -116]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uigeoaxesDemoAppUIFigure = uifigure('Visible', 'off');
            app.uigeoaxesDemoAppUIFigure.Name = 'uigeoaxes Demo App';
            app.GridLayout = uigridlayout(app.uigeoaxesDemoAppUIFigure);
            app.uigeoaxes = uigeoaxes(app.GridLayout);
            app.uigeoaxes.LatitudeLimits = [-69.1825147262203 69.1825147262203];
            app.uigeoaxes.LongitudeLimits = [-97.0873786407767 97.0873786407767];
            app.ControlsGridLayout = uigridlayout(app.GridLayout);
            app.BasemapLabel = uilabel(app.ControlsGridLayout);
            app.BasemapLabel.Text = 'Basemap';
            app.BasemapDropDown = uidropdown(app.ControlsGridLayout);
            app.BasemapDropDown.Items = {'streets-light', 'streets', 'topographic', 'satellite'};
            app.BasemapDropDown.ValueChangedFcn = createCallbackFcn(app, @BasemapDropDownValueChanged, true);
            app.BasemapDropDown.Value = 'streets-light';
            app.ResetViewButton = uibutton(app.ControlsGridLayout, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Text = 'Reset View';
            % Show the figure after all components are created
            app.uigeoaxesDemoAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uigeoaxes_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.uigeoaxesDemoAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uigeoaxesDemoAppUIFigure)
        end
    end
end