classdef uiheatmap_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uiheatmapDemoAppUIFigure  matlab.ui.Figure
        plotButton                matlab.ui.control.Button
        uiheatmap                 uiheatmap
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            load("patients.mat", "LastName", "Age", "Gender", ...
                "SelfAssessedHealthStatus", "Smoker", "Weight", "Location");
            tbl = table(LastName,Age,Gender,SelfAssessedHealthStatus, ...
                Smoker,Weight,Location);
            plot(app.uiheatmap,tbl,'Smoker','SelfAssessedHealthStatus');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uiheatmapDemoAppUIFigure = uifigure('Visible', 'off');
            app.uiheatmapDemoAppUIFigure.Name = 'uiheatmap Demo App';
            app.uiheatmap = uiheatmap(app.uiheatmapDemoAppUIFigure);
            app.plotButton = uibutton(app.uiheatmapDemoAppUIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            % Show the figure after all components are created
            app.uiheatmapDemoAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uiheatmap_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.uiheatmapDemoAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uiheatmapDemoAppUIFigure)
        end
    end
end