classdef uimapaxes_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uimapeaxesDemoAppUIFigure  matlab.ui.Figure
        uimapaxes                  uimapaxes
        plotButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.uimapaxes.ProjectedCRS = projcrs(26918);

             % Draw geographic context
             land = readgeotable("landareas.shp");
             geoplot(app.uimapaxes, land, ...
                 FaceColor=[0.85 0.90 0.80], ...
                 EdgeColor=[0.35 0.35 0.35]);

             hold(app.uimapaxes, "on")

             % Draw route
             latitude = [42.3601 41.8240 40.7128];
             longitude = [-71.0589 -71.4128 -74.0060];

             geoplot(app.uimapaxes, latitude, longitude, "-o", ...
                 Color=[0.85 0.20 0.10], ...
                 LineWidth=2, ...
                 MarkerFaceColor=[0.85 0.20 0.10]);

             hold(app.uimapaxes, "off")

             geolimits(app.uimapaxes, [40 43], [-75 -70]);
             title(app.uimapaxes, "Boston to New York");
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            newmap(app.uimapaxes, projcrs(3857));

            % Draw geographic context
            land = readgeotable("landareas.shp");
            geoplot(app.uimapaxes, land, ...
                FaceColor=[0.85 0.90 0.80], ...
                EdgeColor=[0.35 0.35 0.35]);

            hold(app.uimapaxes, "on")

            % Add locations
            latitude = [42.3601 40.7128];
            longitude = [-71.0589 -74.0060];

            geoscatter(app.uimapaxes, ...
                latitude, longitude, 80, "filled", ...
                MarkerFaceColor=[0.85 0.20 0.10]);

            hold(app.uimapaxes, "off")


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uimapeaxesDemoAppUIFigure = uifigure('Visible', 'off');
            app.uimapeaxesDemoAppUIFigure.Name = 'uimapeaxes Demo App';
            app.plotButton = uibutton(app.uimapeaxesDemoAppUIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.uimapaxes = uimapaxes(app.uimapeaxesDemoAppUIFigure);
            % Show the figure after all components are created
            app.uimapeaxesDemoAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uimapaxes_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.uimapeaxesDemoAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uimapeaxesDemoAppUIFigure)
        end
    end
end