classdef uiparallelplot_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uiparallelplotDemoAppUIFigure  matlab.ui.Figure
        uiparallelplot                 uiparallelplot
        plotButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            load("patients.mat", "Age", "Height", "Weight", ...
                "Systolic", "Diastolic", "Smoker");
            tbl = table(Age,Height,Weight,Systolic,Diastolic,Smoker);
            plot(app.uiparallelplot,tbl, ...
                'CoordinateVariables',{'Age','Height','Weight'}, ...
                'GroupVariable','Smoker');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uiparallelplotDemoAppUIFigure = uifigure('Visible', 'off');
            app.uiparallelplotDemoAppUIFigure.Name = 'uiparallelplot Demo App';
            app.plotButton = uibutton(app.uiparallelplotDemoAppUIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.uiparallelplot = uiparallelplot(app.uiparallelplotDemoAppUIFigure);
            app.uiparallelplot.Data = [0.369246781120215 0.234779913372406 0.744692807074156 0.446783749429806;0.111202755293787 0.353158571222071 0.188955015032545 0.306349472016557;0.780252068321138 0.821194040197959 0.686775433365315 0.508508655381127;0.389738836961253 0.0154034376515551 0.18351115573727 0.51077156417211;0.241691285913833 0.0430238016578078 0.368484596490336 0.817627708322262;0.403912145588115 0.168990029462704 0.62561856072969 0.794831416883453;0.0964545251683886 0.649115474956452 0.780227435151377 0.644318130193692;0.131973292606335 0.73172238565867 0.0811257688657853 0.378609382660268;0.942050590775485 0.647745963136307 0.92938597096873 0.811580458282477;0.956134540229802 0.450923706430945 0.775712678608402 0.532825588799455;0.575208595078466 0.547008892286345 0.486791632403172 0.350727103576883;0.0597795429471558 0.296320805607773 0.435858588580919 0.939001561999887];
            % Show the figure after all components are created
            app.uiparallelplotDemoAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uiparallelplot_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.uiparallelplotDemoAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uiparallelplotDemoAppUIFigure)
        end
    end
end